classdef Wally < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        deviceconfigurationPanel     matlab.ui.container.Panel
        allCheckBox                  matlab.ui.control.CheckBox
        NoiseReductionCheckBox       matlab.ui.control.CheckBox
        CARCheckBox                  matlab.ui.control.CheckBox
        SampleRateDropDownLabel      matlab.ui.control.Label
        SampleRateDropDown           matlab.ui.control.DropDown
        BandpassFilterDropDownLabel  matlab.ui.control.Label
        BandpassFilterDropDown       matlab.ui.control.DropDown
        NotchFilterDropDownLabel     matlab.ui.control.Label
        NotchFilterDropDown          matlab.ui.control.DropDown
        SensitivityDropDownLabel     matlab.ui.control.Label
        SensitivityDropDown          matlab.ui.control.DropDown
        ChannelsEditFieldLabel       matlab.ui.control.Label
        ChannelsEditField            matlab.ui.control.EditField
        CARchannelsEditFieldLabel    matlab.ui.control.Label
        CARchannelsEditField         matlab.ui.control.EditField
        NRchannelsEditFieldLabel     matlab.ui.control.Label
        NRchannelsEditField          matlab.ui.control.EditField
        BipolarpairsEditFieldLabel   matlab.ui.control.Label
        BipolarpairsEditField        matlab.ui.control.EditField
        BipolarCheckBox              matlab.ui.control.CheckBox
        SrcRecDistEditFieldLabel     matlab.ui.control.Label
        SrcRecDistEditField          matlab.ui.control.NumericEditField
        ScansSpinnerLabel            matlab.ui.control.Label
        ScansSpinner                 matlab.ui.control.Spinner
        measurementinfoPanel         matlab.ui.container.Panel
        NameEditFieldLabel           matlab.ui.control.Label
        NameEditField                matlab.ui.control.EditField
        openmontagefileButton        matlab.ui.control.Button
        NotesTextAreaLabel           matlab.ui.control.Label
        NotesTextArea                matlab.ui.control.TextArea
        nomontagefileselectedLabel   matlab.ui.control.Label
        SubjectNameEditFieldLabel    matlab.ui.control.Label
        SubjectNameEditField         matlab.ui.control.EditField
        ExperimenterEditFieldLabel   matlab.ui.control.Label
        ExperimenterEditField        matlab.ui.control.EditField
        FolderEditFieldLabel         matlab.ui.control.Label
        FolderEditField              matlab.ui.control.EditField
        plotsPanel                   matlab.ui.container.Panel
        XLengthsSpinnerLabel         matlab.ui.control.Label
        XLengthsSpinner              matlab.ui.control.Spinner
        ScalingVSpinnerLabel         matlab.ui.control.Label
        ScalingVSpinner              matlab.ui.control.Spinner
        acquisitioncontrolPanel      matlab.ui.container.Panel
        StartEEGButton               matlab.ui.control.Button
        StopButton                   matlab.ui.control.Button
        RecordDataButton             matlab.ui.control.StateButton
    end

    
    properties (Access = private)
        supported_fs
        available_filters
        available_channels
        supported_input_sources
        supported_sensitivities
        current_scaling
        gds_interface
        gnautilus_config
        scope_handle
        acquire_channels_list
        noise_reduction_list
        bipolar_list
        CAR_list
        data_save
        montage
        measurement
        total_channels
        text_handle
        text_ch
        plot_handle
        plot_buffer
        plot_axes
        plot_handle_rot
        plot_axes_rot
        plot_handle_q
        plot_axes_q
        plot_handle_dig
        plot_axes_dig
        playChanList
        recChanList
        playDeviceID
        recDeviceID
        signal
        signal_fs
        rec_data
        pageSize
        save_dir
        total_time
        hpf
    end
    
    methods (Access = private)
        
        function get_filter_items(app)
            
            idx = find([app.available_filters.BandpassFilters.SamplingRate]==app.supported_fs(1,app.SampleRateDropDown.Value));
            upperFreq = [app.available_filters.BandpassFilters(idx).UpperCutoffFrequency];
            lowerFreq = [app.available_filters.BandpassFilters(idx).LowerCutoffFrequency];
            itemsdata = [app.available_filters.BandpassFilters(idx).FilterIndex];
            itemsdata = [-1, itemsdata];
            items = cellstr(string(lowerFreq)+ ' Hz - ' + string(upperFreq) + ' Hz');
            items = strrep(items,'- 0 Hz',' Highpass');
            items = strrep(items,'0 Hz - ','Lowpass ');
            items= [{'none'}, items];
            
            app.BandpassFilterDropDown.Items = items;
            app.BandpassFilterDropDown.ItemsData = itemsdata;
            app.BandpassFilterDropDown.Value = 13 + (app.SampleRateDropDown.Value-1)*25;
            
            idx = find([app.available_filters.NotchFilters.SamplingRate]==app.supported_fs(1,app.SampleRateDropDown.Value));
            upperFreq = [app.available_filters.NotchFilters(idx).UpperCutoffFrequency];
            lowerFreq = [app.available_filters.NotchFilters(idx).LowerCutoffFrequency];
            itemsdata = [app.available_filters.NotchFilters(idx).FilterIndex];
            itemsdata = [-1, itemsdata];
            items = cellstr(string(lowerFreq)+ ' Hz - ' + string(upperFreq) + ' Hz');
            items= [{'none'}, items];
            
            app.NotchFilterDropDown.Items = items;
            app.NotchFilterDropDown.ItemsData = itemsdata;
            app.NotchFilterDropDown.Value = 0 + (app.SampleRateDropDown.Value-1)*2;
        end
        
        function set_eeg_labels(app)
            
            if length(app.montage)==3
                a = app.montage(2);
                a = a{:}';
                if ~app.allCheckBox.Value
                    idx = '[' + (strrep(string(app.ChannelsEditField.Value), '-',':')) + ']';
                    idx  = sort(eval(idx));
                    a = a(idx);
                end
            else
                a = string(num2cell(1:app.total_channels));
            end


            app.text_ch = cellfun(app.text_handle, num2cell(-2*(0:app.total_channels-1)*app.ScalingVSpinner.Value),a);
        end
        
        function plot_setup(app)
            opengl hardware
            
            h = figure('Name', 'EEG');
            set(h, 'MenuBar', 'none', 'ToolBar', 'none');
            set(gcf, 'units','normalized','outerposition',[0.3 0.05 0.7 0.95])
            hp_eeg = uipanel('Title','EEG','FontSize',12,...
                'BackgroundColor','white',...
                'Position',[0 .3 1 .7]);
            hp_dig = uipanel('Title','Digital Inputs','FontSize',12,...
                'BackgroundColor', [.95 .95 .95],...
                'Position',[0 .2 1 .1]);
            hp_rot = uipanel('Title','Rotation','FontSize',12,...
                'BackgroundColor',[.95 .95 .95],...
                'Position',[0 .1 1 .1]);
            hp_q = uipanel('Title','Device Performance','FontSize',12,...
                'BackgroundColor',[.95 .95 .95],...
                'Position',[0 0 1 .1]);
            
            %             fig = figure('Name','EEG data');
            app.plot_buffer = app.XLengthsSpinner.Value*app.supported_fs(1,app.SampleRateDropDown.Value);
            app.plot_buffer = app.plot_buffer-mod(app.plot_buffer, app.ScansSpinner.Value);
            
            Y = NaN(app.plot_buffer,app.total_channels);
            X = 1:app.plot_buffer;
            app.plot_axes = tight_subplot(app,1,1,1, hp_eeg);
            app.plot_handle = line(app.plot_axes, X, Y, 'Color','black');
            app.plot_axes.XLim = [-100 app.plot_buffer];
            app.plot_axes.YLim = [-(app.total_channels*2-1)*app.ScalingVSpinner.Value app.ScalingVSpinner.Value];
            set(gca, 'Visible','off')
            %             set(gcf, 'units','normalized','outerposition',[0.3 0.3 width 0.7]);
            app.text_handle = @(ps,lt) text(-85,ps,lt,'FontSize',10,'Color',.5*[1 1 1],'Parent',app.plot_axes);
            set_eeg_labels(app)


            %             fig_rot = figure('Name','AccelerationXYZ');
            Y_rot = NaN(app.plot_buffer, 3);
            app.plot_axes_rot = tight_subplot(app,1,1,1, hp_rot);
            app.plot_handle_rot = line(app.plot_axes_rot, X, Y_rot);            
            app.plot_axes_rot.XLim = [-100 app.plot_buffer];
            app.plot_axes_rot.YLim = [-4 4];
            legend(app.plot_axes_rot, 'X','Y','Z', 'Location', 'northwest', 'Orientation', 'horizontal') 
            legend('boxoff')
            
            %             app.plot_axes_rot.YLim = [-(app.total_channels*2-1)*app.ScalingVSpinner.Value app.ScalingVSpinner.Value];
            set(gca, 'Visible','off')
            %             set(gcf, 'units','normalized','outerposition',[0.3 0.175 width 0.125]);
            
            %             fig_q = figure('Name','Battery-Quality-Validation');
            Y_q = NaN(app.plot_buffer, 3);
            app.plot_axes_q = tight_subplot(app,1,1,1, hp_q);
            app.plot_handle_q = line(app.plot_axes_q, X, Y_q);
            app.plot_axes_q.XLim = [-100 app.plot_buffer];
            app.plot_axes_q.YLim = [-10 110];
            
            set(gca, 'Visible','off')
            legend(app.plot_axes_q, 'Quality', 'Battery' ,'Validation', 'Location', 'northwest', 'Orientation', 'horizontal') 
            legend('boxoff')
            %             set(gcf, 'units','normalized','outerposition',[0 0.6 0.3 0.4]);
            
            %             fig_dig = figure('Name','Digital Inputs');
            Y_dig = NaN(app.plot_buffer, 1);
            app.plot_axes_dig = tight_subplot(app,1,1,1, hp_dig);
            app.plot_handle_dig = line(app.plot_axes_dig, X, Y_dig, 'Color', 'black');
            app.plot_axes_dig.XLim = [-100 app.plot_buffer];
            app.plot_axes_dig.YLim = [0 10];
            set(gca, 'Visible','off')
            %             set(gcf, 'units','normalized', 'outerposition',[0.3 0.05 width 0.125]);
            
        end
        
        function gnautilus_set_config(app)
            app.acquire_channels_list = choose_channels(app);
            app.noise_reduction_list = choose_noise_reduction(app);
            app.bipolar_list = choose_bipolar(app);
            app.CAR_list = choose_CAR(app);
            app.gnautilus_config.SamplingRate = app.supported_fs(1,app.SampleRateDropDown.Value);
            app.gnautilus_config.NumberOfScans = app.ScansSpinner.Value;%app.supported_fs(2,app.SampleRateDropDown.Value);
            app.gnautilus_config.InputSignal = app.supported_input_sources(1).Value;
            app.gnautilus_config.NoiseReduction = app.NoiseReductionCheckBox.Value;
            app.gnautilus_config.CAR = app.CARCheckBox.Value;
            % additional channels
            app.gnautilus_config.Counter = false;
            app.gnautilus_config.ValidationIndicator = true;
            app.gnautilus_config.AccelerationData = true;
            app.gnautilus_config.LinkQualityInformation = true;
            app.gnautilus_config.BatteryLevel = true;
            app.gnautilus_config.DigitalIOs = true;
            for i=1:size(app.gnautilus_config.Channels,2)
                app.gnautilus_config.Channels(1,i).Available = app.available_channels(i);
                if (app.available_channels(1,i))
                    app.gnautilus_config.Channels(1,i).Acquire = app.acquire_channels_list(i);
                    app.gnautilus_config.Channels(1,i).Sensitivity =  app.supported_sensitivities(app.SensitivityDropDown.Value);
                    app.gnautilus_config.Channels(1,i).UsedForNoiseReduction = app.noise_reduction_list(i);
                    app.gnautilus_config.Channels(1,i).UsedForCAR = app.CAR_list(i);
                    %                     if i == 1
                    %                         app.gnautilus_config.Channels(1,i).BandpassFilterIndex = -1;
                    %                     else
                    app.gnautilus_config.Channels(1,i).BandpassFilterIndex = app.BandpassFilterDropDown.Value;
                    %                     end
                    app.gnautilus_config.Channels(1,i).NotchFilterIndex = app.NotchFilterDropDown.Value;
                    
                    app.gnautilus_config.Channels(1,i).BipolarChannel = app.bipolar_list(i);
                end
            end
            % apply configuration to the gds interface
            app.gds_interface.DeviceConfigurations = app.gnautilus_config;
            % set configuration provided in DeviceConfigurations
            app.gds_interface.SetConfiguration();
            app.current_scaling = app.gds_interface.GetScaling();
                        
            app.hpf = dsp.HighpassFilter('SampleRate',app.gnautilus_config.SamplingRate,...
                'FilterType','IIR',...
                'PassbandFrequency',1,...
                'StopbandFrequency',0.05,...
                'PassbandRipple',0.1,...
                'StopbandAttenuation',80);
        end
        
        function out = choose_channels(app)
            idx = '[' + (strrep(string(app.ChannelsEditField.Value), '-',':')) + ']';
            idx  = sort(eval(idx));
            if app.allCheckBox.Value
                out = 1*app.available_channels;
            else
                out = 0*app.available_channels;
                out(idx) = true;
                out = out.*app.available_channels;
            end
        end
        
        function out = choose_noise_reduction(app)
            idx = '[' + (strrep(string(app.NRchannelsEditField.Value), '-',':')) + ']';
            idx = sort(eval(idx));
            if app.NoiseReductionCheckBox.Value
                out = 0*app.available_channels;
                out(idx) = true;
                out = out.*app.available_channels;
            else
                out = 0*app.available_channels;
            end
        end
        
        function out = choose_CAR(app)
            idx = '[' + (strrep(string(app.CARchannelsEditField.Value), '-',':')) + ']';
            idx = sort(eval(idx));
            if app.CARCheckBox.Value
                out = 0*app.available_channels;
                out(idx) = true;
                out = out.*app.available_channels;
            else
                out = 0*app.available_channels;
            end
            
        end
        
        function out = choose_bipolar(app)
            out = zeros(length(app.available_channels),1) - 1;
            if app.BipolarCheckBox.Value
                bipolar_pairs = '[' + (strrep(string(app.Bipolar_pairsEditField.Value), ',',';')) + ']';
                out(bipolar_pairs(:,1)) = bipolar_pairs(:,2);
            end
            
        end
        
        function acquisition(app)
            app.gds_interface.StartDataAcquisition();
            app.data_save = [];
            app.StopButton.Enable = 'on';
            app.RecordDataButton.Enable = 'on';
            
            Y = NaN(app.plot_buffer, app.total_channels);
            Y_rot = NaN(app.plot_buffer, 3);
            Y_q = NaN(app.plot_buffer, 3);
            Y_dig = NaN(app.plot_buffer, 1);
            
            idx = 1;
            counter = 1;
            
            while app.measurement
                if app.RecordDataButton.Value
                    try
                        [scans, data] = app.gds_interface.GetData(app.gnautilus_config.NumberOfScans);
                    catch ME
                        disp(ME.message);
                        break;
                    end
                    
                    app.data_save = [app.data_save; data];
                    
                    counter = counter + 1;
                    
                else
                    if counter > 1
                        counter = 1;
                        % SAVE HERE
                        mkdir(app.save_dir)
                        save_data(app)
                        app.data_save = [];
                        app.RecordDataButton.Value = 0;
                        RecordDataButtonValueChanged(app);
                    end
                    try
                        [scans, data] = app.gds_interface.GetData(app.gnautilus_config.NumberOfScans);
                    catch ME
                        disp(ME.message);
                        break;
                    end
                end
                
                idx = mod(idx,app.plot_buffer);
                if idx == 0
                    idx = app.plot_buffer;
                end
                
        %        Y(idx:idx+scans-1, :) = app.hpf(data(:,1:app.total_channels)) - 2*(0:app.total_channels-1)*app.ScalingVSpinner.Value;
                Y(idx:idx+scans-1, :) = data(:,1:app.total_channels) - 2*(0:app.total_channels-1)*app.ScalingVSpinner.Value;
                set(app.plot_handle, {'YData'}, num2cell(Y,1)');
                
                Y_rot(idx:idx+scans-1, :) = data(:,app.total_channels+[1 2 3]);
                set(app.plot_handle_rot, {'YData'}, num2cell(Y_rot,1)');
                
                Y_q(idx:idx+scans-1, :) = data(:,app.total_channels+[4 5 7]).*[1 1 50];
                set(app.plot_handle_q, {'YData'}, num2cell(Y_q,1)');
                
                Y_dig(idx:idx+scans-1, :) = data(:,app.total_channels+6);
                set(app.plot_handle_dig, {'YData'}, num2cell(Y_dig,1)');
                
                drawnow limitrate
                idx = idx + scans;
                
                if idx > app.plot_buffer
                    Y = NaN(app.plot_buffer,app.total_channels);
                    Y_rot = NaN(app.plot_buffer,3);
                    Y_q = NaN(app.plot_buffer,3);
                    Y_dig = NaN(app.plot_buffer,1);
                end
                
            end
            
            app.gds_interface.StopDataAcquisition();
            %             save_data(app)
            app.StartEEGButton.Enable = 'on';
            app.StopButton.Enable = 'off';
            app.RecordDataButton.Enable = 'off';
        end
        
        function save_data(app)
            idxBP = app.BandpassFilterDropDown.Value+2 - (app.SampleRateDropDown.Value-1)*22;
            if idxBP < 0
                idxBP = 1;
            end
            Bandpass = (app.BandpassFilterDropDown.Items(idxBP));
            Bandpass = Bandpass{1};
            
            idxNTCH = app.NotchFilterDropDown.Value+2 - (app.SampleRateDropDown.Value-1)*2;
            if idxNTCH < 0
                idxNTCH = 1;
            end
            Notch = (app.NotchFilterDropDown.Items(idxNTCH));
            Notch = Notch{1};
            
            SamplingRate = app.gnautilus_config.SamplingRate;
            
            Measurement = app.data_save(:,1:app.total_channels);
            Rotation = app.data_save(:,app.total_channels+[1 2 3]);
            LinkQuality = app.data_save(:,app.total_channels+4);
            BatteryLevel = app.data_save(:,app.total_channels+5);
            Validation = app.data_save(:,app.total_channels+7);
            DigitalIns = app.data_save(:,app.total_channels+6);
            
            Time =  datetime(clock,'Format','yyMMdd-HHmm');
            fname = strcat(app.NameEditField.Value, '_', string(Time),'.mat');
            Time =  char(Time);
            Notes = app.NotesTextArea.Value;
            Notes = Notes{1};
            Montage = app.montage;
            SubjectID = app.NameEditField.Value;
            Experimenter = app.ExperimenterEditField.Value;
            SubjectName = app.SubjectNameEditField.Value;
            Offset = app.current_scaling.Offset;
            Factor = app.current_scaling.Factor;
            save(strcat(app.save_dir,'\',fname), 'SubjectID', 'SubjectName', 'Experimenter', 'Measurement', 'Rotation', 'LinkQuality', 'BatteryLevel', 'Validation', 'DigitalIns', 'Montage', 'Time', 'SamplingRate', 'Bandpass', 'Notch', 'Offset', 'Factor', 'Notes')
            
        end
        
        function varargout = tight_subplot(~,n,m,i,p)
            [x,y] = ind2sub([m,n],i);
            if nargout > 0
                varargout{1} = axes('parent', p,'units','normalized','position',[(x-1)/m,(y-1)/n,1/m,1/n]);
            else
                axes('parent', p, 'units','normalized','position',[(x-1)/m,(y-1)/n,1/m,1/n]);
            end
        end
        
        function set_buffer_size(app)
            app.ScansSpinner.Limits = [app.supported_fs(2,app.SampleRateDropDown.Value) app.supported_fs(1,app.SampleRateDropDown.Value)];
            %app.ScansSpinner.Value = ceil(0.03*app.supported_fs(1,app.SampleRateDropDown.Value)); % recommended scans
            app.ScansSpinner.Value = app.supported_fs(1,app.SampleRateDropDown.Value)/2;
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %                         if 0
            % Change the current folder to the folder of this file.
            if(~isdeployed)
                cd(fileparts(which(mfilename)));
            end
            
            app.measurement = false;
            % create gtecDeviceInterface object
            app.gds_interface = gtecDeviceInterface();
            % define connection settings (loopback)
            app.gds_interface.IPAddressHost = '127.0.0.1';
            app.gds_interface.IPAddressLocal = '127.0.0.1';
            app.gds_interface.LocalPort = 50224;
            app.gds_interface.HostPort = 50223;
            % get connected devices
            connected_devices = app.gds_interface.GetConnectedDevices();
            % create g.Nautilus configuration object
            app.gnautilus_config = gNautilusDeviceConfiguration();
            % set serial number in g.Nautilus device configuration
            app.gnautilus_config.Name = connected_devices(1,1).Name;
            % set configuration to use functions in gds interface which require device
            % connection`
            app.gds_interface.DeviceConfigurations = app.gnautilus_config;
            % get available channels
            app.available_channels = app.gds_interface.GetAvailableChannels();
            % get supported sensitivities
            app.supported_sensitivities = app.gds_interface.GetSupportedSensitivities();
            % get supported input sources
            app.supported_input_sources = app.gds_interface.GetSupportedInputSources();
            % get supported sample rates
            app.supported_fs = app.gds_interface.GetSupportedSamplingRates();
            
            % set sample rate dropdown
            app.SampleRateDropDown.Items = cellstr(string(app.supported_fs(1,:))+ ' Hz');
            app.SampleRateDropDown.ItemsData = 1:length(app.supported_fs(1,:));
            app.SampleRateDropDown.Value = 2;
            
            % set number of scans
            set_buffer_size(app)
            
            % get available filters and set menu
            app.available_filters = app.gds_interface.GetAvailableFilters();
            get_filter_items(app);
            
            % set sensitivity dropdown
            app.SensitivityDropDown.Items = cellstr('±' + string(app.supported_sensitivities/1000)+ ' mV');
            app.SensitivityDropDown.ItemsData = 1:length(app.supported_sensitivities);
            app.SensitivityDropDown.Value = 2;
            
            % set electrodes
            app.ChannelsEditField.Value = '1-8,17-24';
            
            app.CARchannelsEditField.Enable = 'off';
            app.NRchannelsEditField.Enable = 'off';
            app.BipolarpairsEditField.Enable = 'off';            
        end

        % Value changed function: SampleRateDropDown
        function SampleRateDropDownValueChanged(app, event)
            value = app.SampleRateDropDown.Value;
            get_filter_items(app);
            set_buffer_size(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            %             delete(app.gds_interface)
            delete(app)
        end

        % Value changed function: allCheckBox
        function allCheckBoxValueChanged(app, event)
            value = app.allCheckBox.Value;
            if value == true
                app.ChannelsEditField.Enable = 'off';
            else
                app.ChannelsEditField.Enable = 'on';
            end
        end

        % Value changed function: NoiseReductionCheckBox
        function NoiseReductionCheckBoxValueChanged(app, event)
            value = app.NoiseReductionCheckBox.Value;
            if value == true
                app.NRchannelsEditField.Enable = 'on';
            else
                app.NRchannelsEditField.Enable = 'off';
            end
        end

        % Value changed function: CARCheckBox
        function CARCheckBoxValueChanged(app, event)
            value = app.CARCheckBox.Value;
            if value == true
                app.CARchannelsEditField.Enable = 'on';
            else
                app.CARchannelsEditField.Enable = 'off';
            end
        end

        % Value changed function: BipolarCheckBox
        function BipolarCheckBoxValueChanged(app, event)
            value = app.BipolarCheckBox.Value;
            if value == true
                app.BipolarpairsEditField.Enable = 'on';
            else
                app.BipolarpairsEditField.Enable = 'off';
            end
        end

        % Button pushed function: openmontagefileButton
        function openmontagefileButtonPushed(app, event)
            [file, path] = uigetfile('*.txt', 'Choose event sequence');
            if file
                fid = fopen(strcat(path, file));
                app.montage = textscan(fid, '%d8%s%s');
                fclose(fid);
                app.nomontagefileselectedLabel.Text = file;
                app.nomontagefileselectedLabel.FontColor = [0 0 0];
                
            end
        end

        % Button pushed function: StartEEGButton
        function StartEEGButtonPushed(app, event)
            app.measurement = true;
            if(~isdeployed)
                cd(fileparts(which(mfilename)));
            end
            %             app.save_dir = char(datetime(clock,'Format','yyMMdd'));
            
            app.save_dir = strcat('eeg_measurements\\', app.FolderEditField.Value, '_', char(datetime(clock,'Format','yyMMdd')));
            app.StartEEGButton.Enable = 'off';
            gnautilus_set_config(app);
            app.total_channels = sum(app.acquire_channels_list);
            plot_setup(app);
            acquisition(app)
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.measurement = false;
        end

        % Value changed function: XLengthsSpinner
        function XLengthsSpinnerValueChanged(app, event)
            value = app.XLengthsSpinner.Value;
            app.plot_buffer = ceil(value*app.supported_fs(1,app.SampleRateDropDown.Value)/app.supported_fs(2,app.SampleRateDropDown.Value))*app.supported_fs(2,app.SampleRateDropDown.Value);
            app.plot_axes.XLim = [-100 app.plot_buffer];
            app.plot_axes_dig.XLim = [-100 app.plot_buffer];
            app.plot_axes_rot.XLim = [-100 app.plot_buffer];
            app.plot_axes_q.XLim = [-100 app.plot_buffer];
        end

        % Value changed function: ScalingVSpinner
        function ScalingVSpinnerValueChanged(app, event)
            value = app.ScalingVSpinner.Value;
            app.plot_axes.YLim = [-(app.total_channels*2-1)*value value];
            delete(app.text_ch)
            set_eeg_labels(app)
        end

        % Value changed function: RecordDataButton
        function RecordDataButtonValueChanged(app, event)
            value = app.RecordDataButton.Value;
            if value
                app.RecordDataButton.BackgroundColor = [0 1 0];
            else
                app.RecordDataButton.BackgroundColor = [1 0 0];
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.deviceconfigurationPanel = uipanel(app.UIFigure);
            app.deviceconfigurationPanel.Title = 'device configuration';
            app.allCheckBox = uicheckbox(app.deviceconfigurationPanel);
            app.allCheckBox.ValueChangedFcn = createCallbackFcn(app, @allCheckBoxValueChanged, true);
            app.allCheckBox.Text = 'all';
            app.allCheckBox.Value = true;
            app.NoiseReductionCheckBox = uicheckbox(app.deviceconfigurationPanel);
            app.NoiseReductionCheckBox.ValueChangedFcn = createCallbackFcn(app, @NoiseReductionCheckBoxValueChanged, true);
            app.NoiseReductionCheckBox.Text = 'Noise Reduction';
            app.CARCheckBox = uicheckbox(app.deviceconfigurationPanel);
            app.CARCheckBox.ValueChangedFcn = createCallbackFcn(app, @CARCheckBoxValueChanged, true);
            app.CARCheckBox.Text = 'CAR';
            app.SampleRateDropDownLabel = uilabel(app.deviceconfigurationPanel);
            app.SampleRateDropDownLabel.Text = 'Sample Rate';
            app.SampleRateDropDown = uidropdown(app.deviceconfigurationPanel);
            app.SampleRateDropDown.Items = {};
            app.SampleRateDropDown.ValueChangedFcn = createCallbackFcn(app, @SampleRateDropDownValueChanged, true);
            app.SampleRateDropDown.Value = {};
            app.BandpassFilterDropDownLabel = uilabel(app.deviceconfigurationPanel);
            app.BandpassFilterDropDownLabel.Text = 'Bandpass Filter';
            app.BandpassFilterDropDown = uidropdown(app.deviceconfigurationPanel);
            app.BandpassFilterDropDown.Items = {};
            app.BandpassFilterDropDown.Value = {};
            app.NotchFilterDropDownLabel = uilabel(app.deviceconfigurationPanel);
            app.NotchFilterDropDownLabel.Text = 'Notch Filter';
            app.NotchFilterDropDown = uidropdown(app.deviceconfigurationPanel);
            app.NotchFilterDropDown.Items = {};
            app.NotchFilterDropDown.Value = {};
            app.SensitivityDropDownLabel = uilabel(app.deviceconfigurationPanel);
            app.SensitivityDropDownLabel.Text = 'Sensitivity';
            app.SensitivityDropDown = uidropdown(app.deviceconfigurationPanel);
            app.SensitivityDropDown.Items = {};
            app.SensitivityDropDown.Value = {};
            app.ChannelsEditFieldLabel = uilabel(app.deviceconfigurationPanel);
            app.ChannelsEditFieldLabel.Enable = 'off';
            app.ChannelsEditFieldLabel.Text = 'Channels';
            app.ChannelsEditField = uieditfield(app.deviceconfigurationPanel, 'text');
            app.ChannelsEditField.Enable = 'off';
            app.CARchannelsEditFieldLabel = uilabel(app.deviceconfigurationPanel);
            app.CARchannelsEditFieldLabel.Enable = 'off';
            app.CARchannelsEditFieldLabel.Text = 'CAR channels';
            app.CARchannelsEditField = uieditfield(app.deviceconfigurationPanel, 'text');
            app.CARchannelsEditField.Enable = 'off';
            app.NRchannelsEditFieldLabel = uilabel(app.deviceconfigurationPanel);
            app.NRchannelsEditFieldLabel.Enable = 'off';
            app.NRchannelsEditFieldLabel.Text = 'NR channels';
            app.NRchannelsEditField = uieditfield(app.deviceconfigurationPanel, 'text');
            app.NRchannelsEditField.Enable = 'off';
            app.BipolarpairsEditFieldLabel = uilabel(app.deviceconfigurationPanel);
            app.BipolarpairsEditFieldLabel.Enable = 'off';
            app.BipolarpairsEditFieldLabel.Text = 'Bipolar pairs';
            app.BipolarpairsEditField = uieditfield(app.deviceconfigurationPanel, 'text');
            app.BipolarpairsEditField.Enable = 'off';
            app.BipolarCheckBox = uicheckbox(app.deviceconfigurationPanel);
            app.BipolarCheckBox.ValueChangedFcn = createCallbackFcn(app, @BipolarCheckBoxValueChanged, true);
            app.BipolarCheckBox.Text = 'Bipolar';
            app.SrcRecDistEditFieldLabel = uilabel(app.deviceconfigurationPanel);
            app.SrcRecDistEditFieldLabel.Text = 'Src-Rec Dist';
            app.SrcRecDistEditField = uieditfield(app.deviceconfigurationPanel, 'numeric');
            app.ScansSpinnerLabel = uilabel(app.deviceconfigurationPanel);
            app.ScansSpinnerLabel.Text = 'Scans';
            app.ScansSpinner = uispinner(app.deviceconfigurationPanel);
            app.ScansSpinner.ValueDisplayFormat = '%.0f';
            app.measurementinfoPanel = uipanel(app.UIFigure);
            app.measurementinfoPanel.Title = 'measurement info';
            app.NameEditFieldLabel = uilabel(app.measurementinfoPanel);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.measurementinfoPanel, 'text');
            app.NameEditField.Value = 's00';
            app.openmontagefileButton = uibutton(app.measurementinfoPanel, 'push');
            app.openmontagefileButton.ButtonPushedFcn = createCallbackFcn(app, @openmontagefileButtonPushed, true);
            app.openmontagefileButton.Text = 'open montage file';
            app.NotesTextAreaLabel = uilabel(app.measurementinfoPanel);
            app.NotesTextAreaLabel.Text = 'Notes';
            app.NotesTextArea = uitextarea(app.measurementinfoPanel);
            app.nomontagefileselectedLabel = uilabel(app.measurementinfoPanel);
            app.nomontagefileselectedLabel.Text = 'no montage file selected';
            app.SubjectNameEditFieldLabel = uilabel(app.measurementinfoPanel);
            app.SubjectNameEditFieldLabel.Text = 'Subject  Name';
            app.SubjectNameEditField = uieditfield(app.measurementinfoPanel, 'text');
            app.SubjectNameEditField.Value = 'anon';
            app.ExperimenterEditFieldLabel = uilabel(app.measurementinfoPanel);
            app.ExperimenterEditFieldLabel.Text = 'Experimenter';
            app.ExperimenterEditField = uieditfield(app.measurementinfoPanel, 'text');
            app.ExperimenterEditField.Value = 'GeorgeZ';
            app.FolderEditFieldLabel = uilabel(app.measurementinfoPanel);
            app.FolderEditFieldLabel.Text = 'Folder';
            app.FolderEditField = uieditfield(app.measurementinfoPanel, 'text');
            app.FolderEditField.Value = 'measurements';
            app.plotsPanel = uipanel(app.UIFigure);
            app.plotsPanel.Title = 'plots';
            app.XLengthsSpinnerLabel = uilabel(app.plotsPanel);
            app.XLengthsSpinnerLabel.Text = 'XLength [s]';
            app.XLengthsSpinner = uispinner(app.plotsPanel);
            app.XLengthsSpinner.Limits = [0.1 60];
            app.XLengthsSpinner.ValueChangedFcn = createCallbackFcn(app, @XLengthsSpinnerValueChanged, true);
            app.XLengthsSpinner.Value = 10;
            app.ScalingVSpinnerLabel = uilabel(app.plotsPanel);
            app.ScalingVSpinnerLabel.Text = 'Scaling [μV]';
            app.ScalingVSpinner = uispinner(app.plotsPanel);
            app.ScalingVSpinner.ValueChangedFcn = createCallbackFcn(app, @ScalingVSpinnerValueChanged, true);
            app.ScalingVSpinner.Value = 100;
            app.acquisitioncontrolPanel = uipanel(app.UIFigure);
            app.acquisitioncontrolPanel.Title = 'acquisition control';
            app.StartEEGButton = uibutton(app.acquisitioncontrolPanel, 'push');
            app.StartEEGButton.ButtonPushedFcn = createCallbackFcn(app, @StartEEGButtonPushed, true);
            app.StartEEGButton.Text = 'Start EEG';
            app.StopButton = uibutton(app.acquisitioncontrolPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Enable = 'off';
            app.StopButton.Text = 'Stop';
            app.RecordDataButton = uibutton(app.acquisitioncontrolPanel, 'state');
            app.RecordDataButton.ValueChangedFcn = createCallbackFcn(app, @RecordDataButtonValueChanged, true);
            app.RecordDataButton.Enable = 'off';
            app.RecordDataButton.Text = 'Record Data';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Wally

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end